classdef vesselWidthLineDrawing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ImageadjustPanel            matlab.ui.container.Panel
        MaxSlider                   matlab.ui.control.Slider
        MaxSliderLabel              matlab.ui.control.Label
        MinSlider                   matlab.ui.control.Slider
        MinSliderLabel              matlab.ui.control.Label
        Currentoflines2Panel        matlab.ui.container.Panel
        finishandexitButton         matlab.ui.control.Button
        addalineButton              matlab.ui.control.Button
        drawlinescrossingthevesselfromedgetoedgeLabel  matlab.ui.control.Label
        vesselwidthestimationLabel  matlab.ui.control.Label
        exampleLabel                matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        snapVessel % snapshot image of vessel (rotated and scaled)
        imageH % image height
        imageW % image width
        line1 %drawline figure object
        line2 %drawline figure object
        line3 %drawline figure object
        line4 %drawline figure object
        line5 %drawline figure object
        line6 %drawline figure object
        line7 %drawline figure object
        line8 %drawline figure object
        line9 %drawline figure object
        line10 %drawline figure object
        minBrightness %minimum brightness of image
        minPercent %percent of min-max brightness for minimum image contrast setting
        maxBrightness %maximum brightness of image
        maxPercent %percent of min-max brightness for maximum image contrast setting
        numLines %# of width lines
        diamInPixels %average vessel diameter width, in pixels
        x %x-positions of all drawn lines
        y %y-positions of all drawn lines
        r %ROI #
        filePath %path of input files
        basename %name of input file
    end
    
    methods (Access = private)
        
        function updateImage(app)
            cla(app.UIAxes)

            %redraw image
            tmp = app.snapVessel;
            tmp = tmp - (app.minPercent/100)*255;
            tmp = tmp/(255*(app.maxPercent/100));
            imshow(tmp,'Parent',app.UIAxes)

            %redraw lines
            for i = 1:app.numLines
                position = [app.x(1,i) app.y(1,i); app.x(2,i) app.y(2,i)];
                switch i 
                    case 1
                        app.line1 = drawline(app.UIAxes,'Position',position,'LineWidth',1);
                    case 2
                        app.line2 = drawline(app.UIAxes,'Position',position,'LineWidth',1);
                    case 3
                        app.line3 = drawline(app.UIAxes,'Position',position,'LineWidth',1);
                    case 4
                        app.line4 = drawline(app.UIAxes,'Position',position,'LineWidth',1);
                    case 5
                        app.line5 = drawline(app.UIAxes,'Position',position,'LineWidth',1);
                    case 6
                        app.line6 = drawline(app.UIAxes,'Position',position,'LineWidth',1);
                    case 7
                        app.line7 = drawline(app.UIAxes,'Position',position,'LineWidth',1);
                    case 8
                        app.line8 = drawline(app.UIAxes,'Position',position,'LineWidth',1);
                    case 9
                        app.line9 = drawline(app.UIAxes,'Position',position,'LineWidth',1);
                    case 10
                        app.line10 = drawline(app.UIAxes,'Position',position,'LineWidth',1);
                end
            end
        end
        
        function getLinePositions(app)
            app.x = nan(2,app.numLines);
            app.y = nan(2,app.numLines);
            for i = 1:app.numLines
                switch i
                    case 1
                        xy = app.line1.Position;
                    case 2
                        xy = app.line2.Position;
                    case 3
                        xy = app.line3.Position;
                    case 4
                        xy = app.line4.Position;
                    case 5
                        xy = app.line5.Position;
                    case 6
                        xy = app.line6.Position;
                    case 7
                        xy = app.line7.Position;
                    case 8
                        xy = app.line8.Position;
                    case 9
                        xy = app.line9.Position;
                    case 10
                        xy = app.line10.Position;
                end
                app.x(:,i) = xy(:,1);
                app.y(:,i) = xy(:,2);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, snapVessel, r, filePath, basename)
            %get image properties
            app.snapVessel = snapVessel;
            app.r = r;
            app.filePath = filePath;
            app.basename = basename;
            [app.imageH, app.imageW] = size(app.snapVessel);
            app.minPercent = 1;
            app.maxPercent = 99;

            %set the positions of the first 2 lines
            app.x = [0.25 0.75; 0.25 0.75]*app.imageW;
            app.y = [0.4 0.4; 0.6 0.6]*app.imageH;
            app.numLines = 2;

            %update the image
            updateImage(app)
        end

        % Button pushed function: addalineButton
        function addalineButtonPushed(app, event)
            
            app.numLines = app.numLines + 1;
            position = [0.5*app.imageW 0.4*app.imageH; 0.5*app.imageW 0.6*app.imageH];
            switch app.numLines 
                case 3
                    app.line3 = drawline(app.UIAxes,'Position',position,'LineWidth',1);
                case 4
                    app.line4 = drawline(app.UIAxes,'Position',position,'LineWidth',1);
                case 5
                    app.line5 = drawline(app.UIAxes,'Position',position,'LineWidth',1);
                case 6
                    app.line6 = drawline(app.UIAxes,'Position',position,'LineWidth',1);
                case 7
                    app.line7 = drawline(app.UIAxes,'Position',position,'LineWidth',1);
                case 8
                    app.line8 = drawline(app.UIAxes,'Position',position,'LineWidth',1);
                case 9
                    app.line9 = drawline(app.UIAxes,'Position',position,'LineWidth',1);
                case 10
                    app.line10 = drawline(app.UIAxes,'Position',position,'LineWidth',1);
            end

            %update label
            app.Currentoflines2Panel.Title = ['Current # of lines: ' num2str(app.numLines)];

            if app.numLines==10
                app.Currentoflines2Panel.Title = 'Current # of lines: 10 (max)';
                app.addalineButton.Enable = "off";
            end
        end

        % Button pushed function: finishandexitButton
        function finishandexitButtonPushed(app, event)
            %get latest line positions
            getLinePositions(app);
            
            %calculate vessel width from drawn line positions
            dx = diff(app.x);
            dy = diff(app.y);
            w = sqrt(dx.^2 + dy.^2);
            app.diamInPixels = mean(w);

            vesselWidthImage = getframe(app.UIAxes);
            vesselWidthImage = vesselWidthImage.cdata;
            vesselWidthImage = imresize(vesselWidthImage,[app.imageW app.imageH]);
            saveastiff(vesselWidthImage,fullfile(app.filePath,['AL5_ROI' num2str(app.r) '_SnapshotVesselWidthV_V2_' app.basename '.tif']));

            app.finishandexitButton.UserData=1;
        end

        % Value changed function: MinSlider
        function MinSliderValueChanged(app, event)
            app.minPercent = app.MinSlider.Value;
            updateImage(app)
        end

        % Value changed function: MaxSlider
        function MaxSliderValueChanged(app, event)
            app.maxPercent = app.MaxSlider.Value;
            updateImage(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'exampleSnapshotWidthV2.png');
            app.exampleLabel = uilabel(app.UIFigure);
            app.exampleLabel.Text = 'example:';
            app.vesselwidthestimationLabel = uilabel(app.UIFigure);
            app.vesselwidthestimationLabel.Text = 'vessel width estimation';
            app.drawlinescrossingthevesselfromedgetoedgeLabel = uilabel(app.UIFigure);
            app.drawlinescrossingthevesselfromedgetoedgeLabel.Text = 'drag line ends so they cross the vessel, from edge to edge (2 lines min, more is better)';
            app.Currentoflines2Panel = uipanel(app.UIFigure);
            app.Currentoflines2Panel.Title = 'Current # of lines: 2';
            app.addalineButton = uibutton(app.Currentoflines2Panel, 'push');
            app.addalineButton.ButtonPushedFcn = createCallbackFcn(app, @addalineButtonPushed, true);
            app.addalineButton.Text = 'add a line';
            app.finishandexitButton = uibutton(app.Currentoflines2Panel, 'push');
            app.finishandexitButton.ButtonPushedFcn = createCallbackFcn(app, @finishandexitButtonPushed, true);
            app.finishandexitButton.Text = 'finish and exit';
            app.ImageadjustPanel = uipanel(app.UIFigure);
            app.ImageadjustPanel.Title = 'Image adjust';
            app.MinSliderLabel = uilabel(app.ImageadjustPanel);
            app.MinSliderLabel.Text = 'Min';
            app.MinSlider = uislider(app.ImageadjustPanel);
            app.MinSlider.ValueChangedFcn = createCallbackFcn(app, @MinSliderValueChanged, true);
            app.MinSlider.Value = 1;
            app.MaxSliderLabel = uilabel(app.ImageadjustPanel);
            app.MaxSliderLabel.Text = 'Max';
            app.MaxSlider = uislider(app.ImageadjustPanel);
            app.MaxSlider.ValueChangedFcn = createCallbackFcn(app, @MaxSliderValueChanged, true);
            app.MaxSlider.Value = 99;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vesselWidthLineDrawing(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end